classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        GridLayout    matlab.ui.container.GridLayout
        LeftPanel     matlab.ui.container.Panel
        playButton    matlab.ui.control.Button
        Panel         matlab.ui.container.Panel
        Label_11      matlab.ui.control.Label
        Label_10      matlab.ui.control.Label
        radmLabel     matlab.ui.control.Label
        radsLabel     matlab.ui.control.Label
        VmLabel       matlab.ui.control.Label
        Label_7       matlab.ui.control.Label
        wl_arg_Label  matlab.ui.control.EditField
        Label_6       matlab.ui.control.Label
        wl_m          matlab.ui.control.EditField
        Label_5       matlab.ui.control.Label
        aLabel        matlab.ui.control.EditField
        Label_4       matlab.ui.control.Label
        bLabel        matlab.ui.control.EditField
        Label_3       matlab.ui.control.Label
        wLabel        matlab.ui.control.EditField
        Label_2       matlab.ui.control.Label
        PhLabel       matlab.ui.control.EditField
        EmLabel       matlab.ui.control.EditField
        Label         matlab.ui.control.Label
        RightPanel    matlab.ui.container.Panel
        electromagneticwavesvisualizationLabel  matlab.ui.control.Label
        UIAxes        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        main2 % Description
    end
    
    methods (Access = private)
        
        function res= toNum(~,input)
            res = input;
            if class(res) == "string" ||class(res) == "char"
                res = str2num(res);
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp)
            app.main2 = callingApp;
        end

        % Button pushed function: playButton
        function playButtonPushed(app, event)
            x=0:0.01:10; % x坐标
            zero=0*ones(size(x));
            % 一些参数
            Em= app.toNum(app.EmLabel.Value);     % 电场振幅
            ph0 = app.toNum((app.PhLabel.Value)); 
            w= app.toNum(app.wLabel.Value);    % 角速度
            a = app.toNum(app.aLabel.Value);   % 衰减常数
            b = app.toNum(app.bLabel.Value);   % 相位常数
            wave_impedance_modulus = app.toNum(app.wl_m.Value);
            wave_impedance_angle = app.toNum(app.wl_arg_Label.Value);
            
            % 关闭play button
            app.playButton.Enable = 0;
            % 绘图
            for t=0:4e-10:1e-7
                %电场(指向Y轴)
                E=Em*exp(-a*x).*cos(w*t+ph0-b*x);
                h=quiver3(app.UIAxes,x,zero,zero,zero,E,zero,0,'Color','r');
                set(h , 'maxheadsize' , 0); % 设置箭头大小为0
                axis(app.UIAxes,[-inf,inf,-1.2*Em,1.2*Em,-1.2*(Em/wave_impedance_modulus),1.2*(Em/wave_impedance_modulus)])
                hold(app.UIAxes,'on');
                %磁场（指向Z轴）
                H = (Em/wave_impedance_modulus)*exp(-a*x).*cos(w*t+ph0-b*x-wave_impedance_angle);

                h=quiver3(app.UIAxes,x,zero,zero,zero,zero,H,0,'Color','b');
                set(h , 'maxheadsize' , 0);
                

                xlabel(app.UIAxes,' 电磁波传播方向','fontSize',12);
                ylabel(app.UIAxes,' 电场变化方向(V/m)','fontSize',12);
                zlabel(app.UIAxes,' 磁场变化方向(A/m)','fontSize',12);
                axis(app.UIAxes,[-inf,inf,-1.2*Em,1.2*Em,-1.2*(Em/wave_impedance_modulus),1.2*(Em/wave_impedance_modulus)])
                hold(app.UIAxes,'off');
                pause(0.001);
            end
            % 打开play button
            app.playButton.Enable = 1;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.main2.Button.Enable = 'on';
            app.main2.UIFigure.Visible = 'on';
            delete(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {469, 469};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {354, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.LeftPanel);
            app.Label = uilabel(app.Panel);
            app.Label.Text = '电场场强';
            app.EmLabel = uieditfield(app.Panel, 'text');
            app.EmLabel.Value = '20';
            app.PhLabel = uieditfield(app.Panel, 'text');
            app.PhLabel.Value = '0';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '电场初始相位';
            app.wLabel = uieditfield(app.Panel, 'text');
            app.wLabel.Value = '6*pi*1e8';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '角速度';
            app.bLabel = uieditfield(app.Panel, 'text');
            app.bLabel.Value = '2*pi';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '波数（相位常数）';
            app.aLabel = uieditfield(app.Panel, 'text');
            app.aLabel.Value = '0.08';
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Text = '衰减常数';
            app.wl_m = uieditfield(app.Panel, 'text');
            app.wl_m.Value = '1.20*pi';
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = '波阻抗-幅值';
            app.wl_arg_Label = uieditfield(app.Panel, 'text');
            app.wl_arg_Label.Value = '0.25*pi';
            app.Label_7 = uilabel(app.Panel);
            app.Label_7.Text = '波阻抗-模角';
            app.VmLabel = uilabel(app.Panel);
            app.VmLabel.Text = 'V/m';
            app.radsLabel = uilabel(app.Panel);
            app.radsLabel.Text = 'rad/s';
            app.radmLabel = uilabel(app.Panel);
            app.radmLabel.Text = 'rad/m';
            app.Label_10 = uilabel(app.Panel);
            app.Label_10.Text = '参数设置';
            app.Label_11 = uilabel(app.Panel);
            app.Label_11.Text = '';
            app.playButton = uibutton(app.LeftPanel, 'push');
            app.playButton.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.playButton.Text = 'play';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            zlabel(app.UIAxes, 'Z')
            app.electromagneticwavesvisualizationLabel = uilabel(app.RightPanel);
            app.electromagneticwavesvisualizationLabel.Text = 'electromagnetic waves visualization';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end