classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        infLabel          matlab.ui.control.Label
        Panel_2           matlab.ui.container.Panel
        IncidencereflectionandtransmissionofuniformplanewavesLabel  matlab.ui.control.Label
        Panel             matlab.ui.container.Panel
        DropDown          matlab.ui.control.DropDown
        Label_4           matlab.ui.control.Label
        E0                matlab.ui.control.EditField
        Label_3           matlab.ui.control.Label
        para1             matlab.ui.control.EditField
        EditField_3Label  matlab.ui.control.Label
        para2             matlab.ui.control.EditField
        Label_2           matlab.ui.control.Label
        f                 matlab.ui.control.EditField
        Label             matlab.ui.control.Label
        Button            matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        main2 % Description
         
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp)
            app.main2 = callingApp;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % ------------------ 参数设置 ------------------------

            u0 = 4*pi*10.^(-7);                     % 真空磁导率
            e0 = 1/(36*pi)*10^(-9);                 % 真空介电常数
            er1 = str2num(app.para1.Value);         % 相对介电常数
            er2 = str2num(app.para2.Value);
            w = 2*pi*str2num(app.f.Value);          % 角速度
            k1 = w *sqrt(u0*e0*er1);                % 波数k
            k2 = w *sqrt(u0*e0*er2);
            eta1 = sqrt(u0/(e0*er1));               % 波阻抗
            eta2 = sqrt(u0/(e0*er2));
            Ei0 = str2num(app.E0.Value);            % 电场场强振幅
            
            n = 2;
            lambda1 = 2*pi/k1;
            lambda2 = 2*pi/k2;
            x1 = -n*lambda1:lambda1/75:0;
            x2 = 0:lambda2/75:n*lambda2;
            R = (eta2-eta1)/(eta2+eta1);            % 反射系数
            T = 1+R;                                % 透射系数

            
            %  ------------------- 画图 --------------------------------
            dt = 4e-5;
            x = [x1 x2];
            zero=0*ones(size(x));
            
            % 边界的颜色
            color = zeros(100,100,3);
            color(:,:,1) = 34/255;
            color(:,:,2) = 139/255;
            color(:,:,3) = 34/255;
            
            
            for t = 0:dt:4e-3
            
                %电场(指向y轴)
                e1 = Ei0*(cos(w*t-k1*x1)+R*cos(w*t+k1*x1));
                e2 = T*Ei0*cos(w*t-k2*x2);
                h=quiver3(app.UIAxes,x,zero,zero,zero,[e1 e2],zero,'off','Color','R');
	            set(h , 'maxheadsize' , 0);	%除去箭头
                % 设置坐标轴是必须的，不然尺度会一直变
                
                hold(app.UIAxes,'on');
            
                %磁场（指向z轴）
                h1 = Ei0/eta1*(cos(w*t-k1*x1)-R*cos(w*t+k1*x1));
                h2 = T*Ei0/eta1*cos(w*t-k2*x2);
                h=quiver3(app.UIAxes,x,zero,zero,zero,zero,[h1 h2],'off','Color','B');
                set(h , 'maxheadsize' , 0);	%除去箭头
                
                hold(app.UIAxes,'on'); 
                % 绘制平面
                [Y, Z] = meshgrid(linspace(-2*T*Ei0,2*T*Ei0,100),linspace(-2*T*Ei0/eta1,2*T*Ei0/eta1,100));
                X = zeros(100,100);
                
                mesh(app.UIAxes,X,Y,Z,color,'FaceAlpha',0.1)
                xlabel(app.UIAxes,' 电磁波传播方向','fontSize',12);
                ylabel(app.UIAxes,' 电场强度变化方向','fontSize',12);
                zlabel(app.UIAxes,' 磁场强度变化方向','fontSize',12);
                legend(app.UIAxes,'电场','磁场')
                if app.DropDown.Value == "视角1"
                    view(app.UIAxes,[0,90])
                elseif app.DropDown.Value == "视角2"
                    view(app.UIAxes,[0,0]) 
                end
                axis(app.UIAxes,[-inf inf -2.5*Ei0 2.5*Ei0 -2.5*Ei0/eta1 2.5*Ei0/eta1])

                hold(app.UIAxes,'off');
                pause(0.005);
            end
        end

        % Value changing function: para2
        function para2ValueChanging(app, event)
            app.infLabel.Visible = 1;
            pause(4);
            app.infLabel.Visible = 0;      
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.main2.Button_2.Enable = 'on';
            app.main2.UIFigure.Visible = 'on';
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.UIFigure);
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'icon2_1.png';
            app.Button.Text = '';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '频率 Hz';
            app.f = uieditfield(app.Panel, 'text');
            app.f.Value = '1e3';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '相对介电常数2';
            app.para2 = uieditfield(app.Panel, 'text');
            app.para2.ValueChangingFcn = createCallbackFcn(app, @para2ValueChanging, true);
            app.para2.Value = '4';
            app.EditField_3Label = uilabel(app.Panel);
            app.EditField_3Label.Text = '相对介电常数1';
            app.para1 = uieditfield(app.Panel, 'text');
            app.para1.Value = '2';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '入射波场强  v';
            app.E0 = uieditfield(app.Panel, 'text');
            app.E0.Value = '1';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '视角';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'默认', '视角1', '视角2'};
            app.DropDown.Value = '默认';
            app.Panel_2 = uipanel(app.UIFigure);
            app.IncidencereflectionandtransmissionofuniformplanewavesLabel = uilabel(app.Panel_2);
            app.IncidencereflectionandtransmissionofuniformplanewavesLabel.Text = {'Incidence, reflection and transmission '; 'of '; 'uniform plane waves'};
            app.infLabel = uilabel(app.UIFigure);
            app.infLabel.Visible = 'off';
            app.infLabel.Text = {'当介质2为理想导体'; '设置相对介电常数2为inf'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end