classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel                         matlab.ui.container.Panel
        WaveguideSimulatorLabel       matlab.ui.control.Label
        YSlider                       matlab.ui.control.Slider
        YSliderLabel                  matlab.ui.control.Label
        XSlider                       matlab.ui.control.Slider
        XSliderLabel                  matlab.ui.control.Label
        PlotOptionsPanel              matlab.ui.container.Panel
        PlotsOutdatedLabel            matlab.ui.control.Label
        PlotFieldsButton              matlab.ui.control.Button
        PlotDensityZEditField         matlab.ui.control.NumericEditField
        PlotDensityZEditFieldLabel    matlab.ui.control.Label
        PlotDensityYEditField         matlab.ui.control.NumericEditField
        PlotDensityYEditFieldLabel    matlab.ui.control.Label
        PlotDensityXEditField         matlab.ui.control.NumericEditField
        PlotDensityXEditFieldLabel    matlab.ui.control.Label
        CrossSectionDropDown          matlab.ui.control.DropDown
        CrossSectionDropDownLabel     matlab.ui.control.Label
        PlotTypeDropDown              matlab.ui.control.DropDown
        PlotTypeDropDownLabel         matlab.ui.control.Label
        WaveguideOperationPanel       matlab.ui.container.Panel
        perspectiveDropDown           matlab.ui.control.DropDown
        Label_4                       matlab.ui.control.Label
        HLabel                        matlab.ui.control.Label
        ELabel                        matlab.ui.control.Label
        CheckBox2                     matlab.ui.control.CheckBox
        CheckBox1                     matlab.ui.control.CheckBox
        DFieldsinWaveguideLabel       matlab.ui.control.Label
        OperationalFreqisLessthantheCutoffFreqLabel  matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        ModeNumberLabel               matlab.ui.control.Label
        CalculatedWaveguideParametersLabel  matlab.ui.control.Label
        OperationalFrequencyGHzEditField  matlab.ui.control.NumericEditField
        OperationalFrequencyGHzEditFieldLabel  matlab.ui.control.Label
        nEditField                    matlab.ui.control.NumericEditField
        nEditFieldLabel               matlab.ui.control.Label
        mEditField                    matlab.ui.control.NumericEditField
        mEditFieldLabel               matlab.ui.control.Label
        WaveguideModeTypeButtonGroup  matlab.ui.container.ButtonGroup
        TMButton                      matlab.ui.control.RadioButton
        TEButton                      matlab.ui.control.RadioButton
        UIAxes_4                      matlab.ui.control.UIAxes
        WaveguideGeometryPanel        matlab.ui.container.Panel
        Image2                        matlab.ui.control.Image
        Label                         matlab.ui.control.Label
        UsedefaultwaveguidesettingsorcustomsizesLabel  matlab.ui.control.Label
        GuidebandsDropDown            matlab.ui.control.DropDown
        GuidebandsDropDownLabel       matlab.ui.control.Label
        WaveguideDimensionsLabel      matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        PermeabilityEditField         matlab.ui.control.NumericEditField
        PermeabilityLabel             matlab.ui.control.Label
        PermittivityEditField         matlab.ui.control.NumericEditField
        PermittivityLabel             matlab.ui.control.Label
        WaveguideFillLabel            matlab.ui.control.Label
        zmmEditField                  matlab.ui.control.NumericEditField
        zmmEditFieldLabel             matlab.ui.control.Label
        bmmEditField                  matlab.ui.control.NumericEditField
        bmmEditFieldLabel             matlab.ui.control.Label
        ammEditField                  matlab.ui.control.NumericEditField
        ammEditFieldLabel             matlab.ui.control.Label
        ZSlider                       matlab.ui.control.Slider
        ZSliderLabel                  matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        UIAxes_3                      matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % father object
        main2
        %Waveguide Dimensions
        a 
        b
        z
        
        %Waveguide Fill
        Er
        Eo=8.854.*10^-12;
        Ur
        Uo=4.*pi.*10^-7;
        
        %Field Density
        XDensity
        YDensity
        ZDensity
        XScale
        YScale
        ZScale
        xx
        yy
        zz
        
        %Waveguide Operation
        ModeM
        ModeN
        
        %Fields
        Ex
        Ey
        Ez
        Hx
        Hy
        Hz
        Emag
        Hmag
        
        %Waveguide Parameters
        kc
        fc
        f
        w
        B
        k
        Bx
        By
        Lg
        vp
        wZ
        n
    end
    
    methods (Access = private)
        
        function [Ex Ey Ez Hx Hy Hz] = CalcFields(app)
            app.z=app.zmmEditField.Value;
            app.ZDensity=app.PlotDensityZEditField.Value;
            app.ZScale=linspace(0, app.z/1000, app.ZDensity);
            [app.xx app.yy app.zz]=meshgrid(app.XScale, app.YScale, app.ZScale);
            j=sqrt(-1);
            %calc cutoff freq and wavenumber
            app.kc=sqrt((((app.ModeM.*pi)./(app.a./1000)).^2)+(((app.ModeN.*pi)./(app.b./1000)).^2));
            app.fc=[(1./(2.*pi.*sqrt(app.Er.*app.Eo.*app.Ur.*app.Uo))).*app.kc];
            app.k=app.w.*sqrt(app.Ur.*app.Uo.*app.Er.*app.Eo);
            app.B=sqrt(app.k.^2 - app.kc.^2);
            app.Bx=(app.ModeM.*pi)./(app.a/1000);
            app.By=(app.ModeN.*pi)./(app.b/1000);
            if app.TEButton.Value==1
                
                Ex=[(j.*app.w.*app.Ur.*app.Uo.*app.ModeN.*pi)./((app.b/1000)*app.kc.^2)].*cos((app.ModeM.*pi.*app.xx)./(app.a/1000))...
                    .*sin((app.ModeN.*pi.*app.yy)./(app.b/1000)).*exp(-j.*app.B.*app.zz);
                
                Ey=[(-j.*app.w.*app.Ur.*app.Uo.*app.ModeM.*pi)./((app.a/1000).*app.kc.^2)].*sin((app.ModeM.*pi.*app.xx)./(app.a/1000))...
                    .*cos((app.ModeN.*pi.*app.yy)./(app.b/1000)).*exp(-j.*app.B.*app.zz);
                
                Ez=zeros(size(app.xx));
                
                Hx=[(j.*app.B.*app.ModeM.*pi)./((app.a/1000).*app.kc.^2)].*sin((app.ModeM.*pi.*app.xx)./(app.a/1000))...
                    .*cos((app.ModeN.*pi.*app.yy)./(app.b/1000)).*exp(-j.*app.B.*app.zz);
                
                Hy=[(j.*app.B.*app.ModeN.*pi)./((app.b/1000).*app.kc.^2)].*cos((app.ModeM.*pi.*app.xx)./(app.a/1000))...
                    .*sin((app.ModeN.*pi.*app.yy)./(app.b/1000)).*exp(-j.*app.B.*app.zz);
                
                Hz=cos((app.ModeM.*pi.*app.xx)./(app.a/1000)).*cos((app.ModeN.*pi.*app.xx)./(app.b/1000)).*exp(-j.*app.B.*app.zz);
            else
                    Ex=[(-j.*app.B.*app.ModeM.*pi)./((app.a/1000).*app.kc.^2)].*cos((app.ModeM.*pi.*app.xx)./(app.a/1000))...
                        .*sin((app.ModeN.*pi.*app.yy)./(app.b/1000)).*exp(-j.*app.B.*app.zz);
                    
                    Ey=[(-j.*app.B.*app.ModeN.*pi)./((app.b/1000).*app.kc.^2)].*sin((app.ModeM.*pi.*app.xx)./(app.a/1000))...
                        .*cos((app.ModeN.*pi.*app.yy)./(app.b/1000)).*exp(-j.*app.B.*app.zz)
                    
                    Ez=sin((app.ModeM.*pi.*app.xx)./(app.a/1000)).*sin((app.ModeN.*pi.*app.yy)./(app.b/1000)).*exp(-j.*app.B.*app.zz);
                    
                    Hx=[(j.*app.w.*app.Eo.*app.Er.*app.ModeN.*pi)./((app.b/1000).*app.kc.^2)].*sin((app.ModeM.*pi.*app.xx)./(app.a/1000))...
                        .*cos((app.ModeN.*pi.*app.yy)./(app.b/1000)).*exp(-j.*app.B.*app.zz);
                    
                    Hy=[(-j.*app.w.*app.Eo.*app.Er.*app.ModeM.*pi)./((app.a/1000).*app.kc.^2)].*cos((app.ModeM.*pi.*app.xx)./(app.a/1000))...
                        .*sin((app.ModeN.*pi.*app.yy)./(app.b/1000)).*exp(-j.*app.B.*app.zz);
                    
                    Hz=zeros(size(app.xx));
            end
        end
        
        function results = plotF(app)
            %plot fields
            app.PlotsOutdatedLabel.Visible='off';
            if app.CrossSectionDropDown.Value=="x-y"
                rat=app.a/app.b;
                app.ZSlider.Visible='on';
                app.ZSliderLabel.Visible='on';
                app.XSlider.Visible='off';
                app.XSliderLabel.Visible='off';
                app.YSlider.Visible='off';
                app.YSliderLabel.Visible='off'
                
                dz=(app.ZSlider.Value/1000)-app.ZScale;
                [minZ ind]=min(abs(dz));
                
                if app.PlotTypeDropDown.Value=="Quiver"
                    cla(app.UIAxes);
                    quiver(app.UIAxes, app.XScale*1000, app.YScale*1000, real(app.Ex(:,:,ind)), real(app.Ey(:,:,ind)));
                    app.UIAxes.XLabel.String='x (mm)';
                    app.UIAxes.YLabel.String='y (mm)';
                    app.UIAxes.XLim=[0 app.a];
                    app.UIAxes.YLim=[0 app.b];
                    app.UIAxes.Box='on';
                    app.UIAxes.PlotBoxAspectRatio=[rat 1 1];
                    app.UIAxes.Title.String='E-Field'
                    
                    cla(app.UIAxes_2);
                    quiver(app.UIAxes_2, app.XScale*1000, app.YScale*1000, real(app.Hx(:,:,ind)), real(app.Hy(:,:,ind)),'r');
                    app.UIAxes_2.XLabel.String='x (mm)';
                    app.UIAxes_2.YLabel.String='y (mm)';
                    app.UIAxes_2.XLim=[0 app.a];
                    app.UIAxes_2.YLim=[0 app.b];
                    app.UIAxes_2.Box='on';
                    app.UIAxes_2.PlotBoxAspectRatio=[rat 1 1];
                    app.UIAxes_2.Title.String='H-Field'
                    
                    cla(app.UIAxes_3);
                    app.UIAxes_3.Visible='on'
                    quiver(app.UIAxes_3, app.XScale*1000, app.YScale*1000, real(app.Ex(:,:,ind)), real(app.Ey(:,:,ind)));
                    hold(app.UIAxes_3,'on');
                    quiver(app.UIAxes_3, app.XScale*1000, app.YScale*1000, real(app.Hx(:,:,ind)), real(app.Hy(:,:,ind)),'r');
                    app.UIAxes_3.XLabel.String='x (mm)';
                    app.UIAxes_3.YLabel.String='y (mm)';
                    app.UIAxes_3.XLim=[0 app.a];
                    app.UIAxes_3.YLim=[0 app.b];
                    app.UIAxes_3.Box='on';
                    app.UIAxes_3.PlotBoxAspectRatio=[rat 1 1];
                    app.UIAxes_3.Title.String='E-Field and H-Field'
                    legend(app.UIAxes_3, 'E-field','H-field');
                    hold(app.UIAxes_3,'off');
                    
                elseif app.PlotTypeDropDown.Value=="Quiver w/ Contour"
                    cla(app.UIAxes);
                    quiver(app.UIAxes, app.XScale*1000, app.YScale*1000, real(app.Ex(:,:,ind)), real(app.Ey(:,:,ind)));
                    hold(app.UIAxes,'on')
                    contour(app.UIAxes, app.XScale*1000, app.YScale*1000, real(app.Emag(:,:,ind)));
                    hold(app.UIAxes,'off')
                    app.UIAxes.XLabel.String='x (mm)';
                    app.UIAxes.YLabel.String='y (mm)';
                    app.UIAxes.XLim=[0 app.a];
                    app.UIAxes.YLim=[0 app.b];
                    app.UIAxes.Box='on';
                    app.UIAxes.PlotBoxAspectRatio=[rat 1 1];
                    app.UIAxes.Title.String='E-Field'
                    contour(app.UIAxes_2, app.XScale*1000, app.YScale*1000, real(app.Hmag(:,:,ind)));
                    
                    cla(app.UIAxes_2);
                    quiver(app.UIAxes_2, app.XScale*1000, app.YScale*1000, real(app.Hx(:,:,ind)), real(app.Hy(:,:,ind)),'r');
                    hold(app.UIAxes_2,'on')
                    contour(app.UIAxes_2, app.XScale*1000, app.YScale*1000, real(app.Hmag(:,:,ind)));
                    hold(app.UIAxes_2,'off')
                    app.UIAxes_2.XLabel.String='x (mm)';
                    app.UIAxes_2.YLabel.String='y (mm)';
                    app.UIAxes_2.XLim=[0 app.a];
                    app.UIAxes_2.YLim=[0 app.b];
                    app.UIAxes_2.Box='on';
                    app.UIAxes_2.PlotBoxAspectRatio=[rat 1 1];
                    app.UIAxes_2.Title.String='H-Field'
                    
                    cla(app.UIAxes_3);
                    app.UIAxes_3.Visible='on'
                    quiver(app.UIAxes_3, app.XScale*1000, app.YScale*1000, real(app.Ex(:,:,ind)), real(app.Ey(:,:,ind)));
                    hold(app.UIAxes_3,'on');
                    quiver(app.UIAxes_3, app.XScale*1000, app.YScale*1000, real(app.Hx(:,:,ind)), real(app.Hy(:,:,ind)),'r');
                    app.UIAxes_3.XLabel.String='x (mm)';
                    app.UIAxes_3.YLabel.String='y (mm)';
                    app.UIAxes_3.XLim=[0 app.a];
                    app.UIAxes_3.YLim=[0 app.b];
                    app.UIAxes_3.Box='on';
                    app.UIAxes_3.PlotBoxAspectRatio=[rat 1 1];
                    app.UIAxes_3.Title.String='E-Field and H-Field'
                    legend(app.UIAxes_3, 'E-field','H-field');
                    hold(app.UIAxes_3,'off');
                elseif app.PlotTypeDropDown.Value=="Image"
                    cla(app.UIAxes);
                    imagesc(app.UIAxes, app.XScale*1000, app.YScale*1000, real(app.Emag(:,:,ind)));
                    app.UIAxes.XLabel.String='x (mm)';
                    app.UIAxes.YLabel.String='y (mm)';
                    app.UIAxes.XLim=[0 app.a];
                    app.UIAxes.YLim=[0 app.b];
                    app.UIAxes.Box='on';
                    app.UIAxes.PlotBoxAspectRatio=[rat 1 1];
                    app.UIAxes.Title.String='E-Field'
                    
                    cla(app.UIAxes_2);
                    imagesc(app.UIAxes_2, app.XScale*1000, app.YScale*1000, real(app.Hmag(:,:,ind)));
                    app.UIAxes_2.XLabel.String='x (mm)';
                    app.UIAxes_2.YLabel.String='y (mm)';
                    app.UIAxes_2.XLim=[0 app.a];
                    app.UIAxes_2.YLim=[0 app.b];
                    app.UIAxes_2.Box='on';
                    app.UIAxes_2.PlotBoxAspectRatio=[rat 1 1];
                    app.UIAxes_2.Title.String='H-Field'
                    
                    cla(app.UIAxes_3);
                    app.UIAxes_3.Visible='off';
                    app.UIAxes_3.Legend.Visible='off'
%                     app.UIAxes_3.XLabel.String='x (mm)';
%                     app.UIAxes_3.YLabel.String='y (mm)';
%                     app.UIAxes_3.XLim=[0 app.a];
%                     app.UIAxes_3.YLim=[0 app.b];
%                     app.UIAxes_3.Box='on';
%                     app.UIAxes_3.PlotBoxAspectRatio=[rat 1 1];
%                     app.UIAxes_3.Title.String='E-Field and H-Field'
                    
                elseif app.PlotTypeDropDown.Value=="Contour"
                    cla(app.UIAxes);
                    contourf(app.UIAxes, app.XScale*1000, app.YScale*1000, real(app.Emag(:,:,ind)));
                    app.UIAxes.XLabel.String='x (mm)';
                    app.UIAxes.YLabel.String='y (mm)';
                    app.UIAxes.XLim=[0 app.a];
                    app.UIAxes.YLim=[0 app.b];
                    app.UIAxes.Box='on';
                    app.UIAxes.PlotBoxAspectRatio=[rat 1 1];
                    app.UIAxes.Title.String='E-Field'
                    
                    cla(app.UIAxes_2);
                    contourf(app.UIAxes_2, app.XScale*1000, app.YScale*1000, real(app.Hmag(:,:,ind)));
                    app.UIAxes_2.XLabel.String='x (mm)';
                    app.UIAxes_2.YLabel.String='y (mm)';
                    app.UIAxes_2.XLim=[0 app.a];
                    app.UIAxes_2.YLim=[0 app.b];
                    app.UIAxes_2.Box='on';
                    app.UIAxes_2.PlotBoxAspectRatio=[rat 1 1];
                    app.UIAxes_2.Title.String='H-Field'
                    
                    cla(app.UIAxes_3);
                    app.UIAxes_3.Visible='off';
                    app.UIAxes_3.Legend.Visible='off'
%                     app.UIAxes_3.XLabel.String='x (mm)';
%                     app.UIAxes_3.YLabel.String='y (mm)';
%                     app.UIAxes_3.XLim=[0 app.a];
%                     app.UIAxes_3.YLim=[0 app.b];
%                     app.UIAxes_3.Box='on';
%                     app.UIAxes_3.PlotBoxAspectRatio=[rat 1 1];
%                     app.UIAxes_3.Title.String='E-Field and H-Field'
                    
                end
            elseif app.CrossSectionDropDown.Value=="y-z"
                rat=app.b/app.z;
                app.ZSlider.Visible='off';
                app.ZSliderLabel.Visible='off';
                app.XSlider.Visible='on';
                app.XSliderLabel.Visible='on';
                app.YSlider.Visible='off';
                app.YSliderLabel.Visible='off'
                
                dx=app.XSlider.Value-(app.XScale*1000);
                [minX ind]=min(abs(dx));
                ind=ind(1);
                
                Ez1(:,:)=app.Ez(:,ind,:);
                Ey1(:,:)=app.Ey(:,ind,:);
                Hz1(:,:)=app.Hz(:,ind,:);
                Hy1(:,:)=app.Hy(:,ind,:);
                Emag1(:,:)=app.Emag(:,ind,:);
                Hmag1(:,:)=app.Hmag(:,ind,:);
                if app.PlotTypeDropDown.Value=="Quiver"
                    cla(app.UIAxes);
                    quiver(app.UIAxes, app.ZScale*1000, app.YScale*1000, real(Ez1), real(Ey1));
                    app.UIAxes.XLabel.String='z (mm)';
                    app.UIAxes.YLabel.String='y (mm)';
                    app.UIAxes.XLim=[0 app.z];
                    app.UIAxes.YLim=[0 app.b];
                    app.UIAxes.Box='on';
                    app.UIAxes.PlotBoxAspectRatio=[1 rat 1];
                    app.UIAxes.Title.String='E-Field'
                    
                    cla(app.UIAxes_2);
                    quiver(app.UIAxes_2, app.ZScale*1000, app.YScale*1000, real(Hz1), real(Hy1),'r');
                    app.UIAxes_2.XLabel.String='z (mm)';
                    app.UIAxes_2.YLabel.String='y (mm)';
                    app.UIAxes_2.XLim=[0 app.z];
                    app.UIAxes_2.YLim=[0 app.b];
                    app.UIAxes_2.Box='on';
                    app.UIAxes_2.PlotBoxAspectRatio=[1 rat 1];
                    app.UIAxes_2.Title.String='H-Field'
                    
                    cla(app.UIAxes_3);
                    app.UIAxes_3.Visible='on'
                    quiver(app.UIAxes_3, app.ZScale*1000, app.YScale*1000, real(Ez1), real(Ey1));
                    hold(app.UIAxes_3,'on');
                    quiver(app.UIAxes_3, app.ZScale*1000, app.YScale*1000, real(Hz1), real(Hy1),'r');
                    app.UIAxes_3.XLabel.String='z (mm)';
                    app.UIAxes_3.YLabel.String='y (mm)';
                    app.UIAxes_3.XLim=[0 app.z];
                    app.UIAxes_3.YLim=[0 app.b];
                    app.UIAxes_3.Box='on';
                    app.UIAxes_3.PlotBoxAspectRatio=[1 rat 1];
                    app.UIAxes_3.Title.String='E-Field and H-Field'
                    legend(app.UIAxes_3, 'E-field','H-field');
                    hold(app.UIAxes_3,'off');
                elseif app.PlotTypeDropDown.Value=="Quiver w/ Contour"
                    cla(app.UIAxes);
                    quiver(app.UIAxes, app.ZScale*1000, app.YScale*1000, real(Ez1), real(Ey1));
                    hold(app.UIAxes,'on')
                    contour(app.UIAxes, app.ZScale*1000, app.YScale*1000, real(Emag1));
                    hold(app.UIAxes,'off');
                    app.UIAxes.XLabel.String='z (mm)';
                    app.UIAxes.YLabel.String='y (mm)';
                    app.UIAxes.XLim=[0 app.z];
                    app.UIAxes.YLim=[0 app.b];
                    app.UIAxes.Box='on';
                    app.UIAxes.PlotBoxAspectRatio=[1 rat 1];
                    app.UIAxes.Title.String='E-Field'
                    
                    cla(app.UIAxes_2);
                    quiver(app.UIAxes_2, app.ZScale*1000, app.YScale*1000, real(Hz1), real(Hy1),'r');
                    hold(app.UIAxes_2,'on')
                    contour(app.UIAxes_2, app.ZScale*1000, app.YScale*1000, real(Hmag1));
                    hold(app.UIAxes_2,'off')
                    app.UIAxes_2.XLabel.String='z (mm)';
                    app.UIAxes_2.YLabel.String='y (mm)';
                    app.UIAxes_2.XLim=[0 app.z];
                    app.UIAxes_2.YLim=[0 app.b];
                    app.UIAxes_2.Box='on';
                    app.UIAxes_2.PlotBoxAspectRatio=[1 rat 1];
                    app.UIAxes_2.Title.String='H-Field'
                    
                    cla(app.UIAxes_3);
                    app.UIAxes_3.Visible='on'
                    quiver(app.UIAxes_3, app.ZScale*1000, app.YScale*1000, real(Ez1), real(Ey1));
                    hold(app.UIAxes_3,'on');
                    quiver(app.UIAxes_3, app.ZScale*1000, app.YScale*1000, real(Hz1), real(Hy1),'r');
                    app.UIAxes_3.XLabel.String='z (mm)';
                    app.UIAxes_3.YLabel.String='y (mm)';
                    app.UIAxes_3.XLim=[0 app.z];
                    app.UIAxes_3.YLim=[0 app.b];
                    app.UIAxes_3.Box='on';
                    app.UIAxes_3.PlotBoxAspectRatio=[1 rat 1];
                    app.UIAxes_3.Title.String='E-Field and H-Field'
                    legend(app.UIAxes_3, 'E-field','H-field');
                    hold(app.UIAxes_3,'off');
                elseif app.PlotTypeDropDown.Value=="Image"
                    cla(app.UIAxes);
                    imagesc(app.UIAxes, app.ZScale*1000, app.YScale*1000, real(Emag1));
                    app.UIAxes.XLabel.String='z (mm)';
                    app.UIAxes.YLabel.String='y (mm)';
                    app.UIAxes.XLim=[0 app.z];
                    app.UIAxes.YLim=[0 app.b];
                    app.UIAxes.Box='on';
                    app.UIAxes.PlotBoxAspectRatio=[1 rat 1];
                    app.UIAxes.Title.String='E-Field'

                    cla(app.UIAxes_2);
                    imagesc(app.UIAxes_2, app.ZScale*1000, app.YScale*1000, real(Hmag1));
                    app.UIAxes_2.XLabel.String='z (mm)';
                    app.UIAxes_2.YLabel.String='y (mm)';
                    app.UIAxes_2.XLim=[0 app.z];
                    app.UIAxes_2.YLim=[0 app.b];
                    app.UIAxes_2.Box='on';
                    app.UIAxes_2.PlotBoxAspectRatio=[1 rat 1];
                    app.UIAxes_2.Title.String='H-Field'
                    
                    cla(app.UIAxes_3);
                    app.UIAxes_3.Visible='off';
                    app.UIAxes_3.Legend.Visible='off'
%                     app.UIAxes_3.XLabel.String='z (mm)';
%                     app.UIAxes_3.YLabel.String='y (mm)';
%                     app.UIAxes_3.XLim=[0 app.z];
%                     app.UIAxes_3.YLim=[0 app.b];
%                     app.UIAxes_3.Box='on';
%                     app.UIAxes_3.PlotBoxAspectRatio=[1 rat 1];
%                     app.UIAxes_3.Title.String='E-Field and H-Field'
                    
                    
                elseif app.PlotTypeDropDown.Value=="Contour"
                    cla(app.UIAxes);
                    contourf(app.UIAxes, app.ZScale*1000, app.YScale*1000, real(Emag1));
                    app.UIAxes.XLabel.String='z (mm)';
                    app.UIAxes.YLabel.String='y (mm)';
                    app.UIAxes.XLim=[0 app.z];
                    app.UIAxes.YLim=[0 app.b];
                    app.UIAxes.Box='on';
                    app.UIAxes.PlotBoxAspectRatio=[1 rat 1];
                    app.UIAxes.Title.String='E-Field'
                    
                    cla(app.UIAxes_2);
                    contourf(app.UIAxes_2, app.ZScale*1000, app.YScale*1000, real(Hmag1));
                    app.UIAxes_2.XLabel.String='z (mm)';
                    app.UIAxes_2.YLabel.String='y (mm)';
                    app.UIAxes_2.XLim=[0 app.z];
                    app.UIAxes_2.YLim=[0 app.b];
                    app.UIAxes_2.Box='on';
                    app.UIAxes_2.PlotBoxAspectRatio=[1 rat 1];
                    app.UIAxes_2.Title.String='H-Field'
                    
                    cla(app.UIAxes_3);
                    app.UIAxes_3.Visible='off';
                    app.UIAxes_3.Legend.Visible='off'
%                     app.UIAxes_3.XLabel.String='z (mm)';
%                     app.UIAxes_3.YLabel.String='y (mm)';
%                     app.UIAxes_3.XLim=[0 app.z];
%                     app.UIAxes_3.YLim=[0 app.b];
%                     app.UIAxes_3.Box='on';
%                     app.UIAxes_3.PlotBoxAspectRatio=[1 rat 1];
%                     app.UIAxes_3.Title.String='E-Field and H-Field'
                end
            elseif app.CrossSectionDropDown.Value=="x-z"
                rat=app.a/app.z;
                app.ZSlider.Visible='off';
                app.ZSliderLabel.Visible='off';
                app.XSlider.Visible='off';
                app.XSliderLabel.Visible='off';
                app.YSlider.Visible='on';
                app.YSliderLabel.Visible='on'
                dy=app.YSlider.Value-(app.YScale*1000);
                [minX ind]=min(abs(dy));
                ind=ind(1);
                Ez1(:,:)=app.Ez(ind,:,:);
                Ex1(:,:)=app.Ex(ind,:,:);
                Hz1(:,:)=app.Hz(ind,:,:);
                Hx1(:,:)=app.Hx(ind,:,:);
                Emag1(:,:)=app.Emag(ind,:,:);
                Hmag1(:,:)=app.Hmag(ind,:,:);
                
                if app.PlotTypeDropDown.Value=="Quiver"
                    cla(app.UIAxes);
                    quiver(app.UIAxes, app.ZScale*1000, app.XScale*1000, real(Ez1), real(Ex1),'Marker','.');
                    app.UIAxes.XLabel.String='z (mm)';
                    app.UIAxes.YLabel.String='x (mm)';
                    app.UIAxes.XLim=[0 app.z];
                    app.UIAxes.YLim=[0 app.a];
                    app.UIAxes.Box='on';
                    app.UIAxes.PlotBoxAspectRatio=[1 rat 1];
                    app.UIAxes.Title.String='E-Field'
                    
                    cla(app.UIAxes_2);
                    quiver(app.UIAxes_2, app.ZScale*1000, app.XScale*1000, real(Hz1), real(Hx1),'r');
                    app.UIAxes_2.XLabel.String='z (mm)';
                    app.UIAxes_2.YLabel.String='x (mm)';
                    app.UIAxes_2.XLim=[0 app.z];
                    app.UIAxes_2.YLim=[0 app.a];
                    app.UIAxes_2.Box='on';
                    app.UIAxes_2.PlotBoxAspectRatio=[1 rat 1];
                    app.UIAxes_2.Title.String='H-Field'
                    
                    cla(app.UIAxes_3);
                    app.UIAxes_3.Visible='on'
                    quiver(app.UIAxes_3, app.ZScale*1000, app.XScale*1000, real(Ez1), real(Ex1),'Marker','.');
                    hold(app.UIAxes_3,'on');
                    quiver(app.UIAxes_3, app.ZScale*1000, app.XScale*1000, real(Hz1), real(Hx1),'r');
                    app.UIAxes_3.XLabel.String='z (mm)';
                    app.UIAxes_3.YLabel.String='x (mm)';
                    app.UIAxes_3.XLim=[0 app.z];
                    app.UIAxes_3.YLim=[0 app.a];
                    app.UIAxes_3.Box='on';
                    app.UIAxes_3.PlotBoxAspectRatio=[1 rat 1];
                    app.UIAxes_3.Title.String='E-Field and H-Field'
                    legend(app.UIAxes_3, 'E-field','H-field');
                    
                elseif app.PlotTypeDropDown.Value=="Quiver w/ Contour"
                    cla(app.UIAxes);
                    quiver(app.UIAxes, app.ZScale*1000, app.XScale*1000, real(Ez1), real(Ex1),'Marker','.');
                    hold(app.UIAxes,'on')
                    contour(app.UIAxes, app.ZScale*1000, app.XScale*1000, real(Emag1));
                    hold(app.UIAxes,'off')
                    app.UIAxes.XLabel.String='z (mm)';
                    app.UIAxes.YLabel.String='x (mm)';
                    app.UIAxes.XLim=[0 app.z];
                    app.UIAxes.YLim=[0 app.a];
                    app.UIAxes.Box='on';
                    app.UIAxes.PlotBoxAspectRatio=[1 rat 1];
                    app.UIAxes.Title.String='E-Field'
                    
                    cla(app.UIAxes_2);
                    quiver(app.UIAxes_2, app.ZScale*1000, app.XScale*1000, real(Hz1), real(Hx1),'r');
                    hold(app.UIAxes_2,'on')
                    contour(app.UIAxes_2, app.ZScale*1000, app.XScale*1000, real(Hmag1));
                    hold(app.UIAxes_2,'off')
                    app.UIAxes_2.XLabel.String='z (mm)';
                    app.UIAxes_2.YLabel.String='x (mm)';
                    app.UIAxes_2.XLim=[0 app.z];
                    app.UIAxes_2.YLim=[0 app.a];
                    app.UIAxes_2.Box='on';
                    app.UIAxes_2.PlotBoxAspectRatio=[1 rat 1];
                    app.UIAxes_2.Title.String='H-Field'
                    
                    cla(app.UIAxes_3);
                    app.UIAxes_3.Visible='on'
                    quiver(app.UIAxes_3, app.ZScale*1000, app.XScale*1000, real(Ez1), real(Ex1),'Marker','.');
                    hold(app.UIAxes_3,'on');
                    quiver(app.UIAxes_3, app.ZScale*1000, app.XScale*1000, real(Hz1), real(Hx1),'r');
                    app.UIAxes_3.XLabel.String='z (mm)';
                    app.UIAxes_3.YLabel.String='x (mm)';
                    app.UIAxes_3.XLim=[0 app.z];
                    app.UIAxes_3.YLim=[0 app.a];
                    app.UIAxes_3.Box='on';
                    app.UIAxes_3.PlotBoxAspectRatio=[1 rat 1];
                    app.UIAxes_3.Title.String='E-Field and H-Field'
                    legend(app.UIAxes_3, 'E-field','H-field');
                    
                elseif app.PlotTypeDropDown.Value=="Image"
                    cla(app.UIAxes);
                    imagesc(app.UIAxes, app.ZScale*1000, app.XScale*1000, real(Emag1));
                    app.UIAxes.XLabel.String='z (mm)';
                    app.UIAxes.YLabel.String='x (mm)';
                    app.UIAxes.XLim=[0 app.z];
                    app.UIAxes.YLim=[0 app.a];
                    app.UIAxes.Box='on';
                    app.UIAxes.PlotBoxAspectRatio=[1 rat 1];
                    app.UIAxes.Title.String='E-Field'
                    
                    cla(app.UIAxes_2);
                    imagesc(app.UIAxes_2, app.ZScale*1000, app.XScale*1000, real(Hmag1));
                    app.UIAxes_2.XLabel.String='z (mm)';
                    app.UIAxes_2.YLabel.String='x (mm)';
                    app.UIAxes_2.XLim=[0 app.z];
                    app.UIAxes_2.YLim=[0 app.a];
                    app.UIAxes_2.Box='on';
                    app.UIAxes_2.PlotBoxAspectRatio=[1 rat 1];
                    app.UIAxes_2.Title.String='H-Field'
                    
                    cla(app.UIAxes_3);
                    app.UIAxes_3.Visible='off';
                    app.UIAxes_3.Legend.Visible='off'
%                     app.UIAxes_3.XLabel.String='z (mm)';
%                     app.UIAxes_3.YLabel.String='x (mm)';
%                     app.UIAxes_3.XLim=[0 app.z];
%                     app.UIAxes_3.YLim=[0 app.a];
%                     app.UIAxes_3.Box='on';
%                     app.UIAxes_3.PlotBoxAspectRatio=[1 rat 1];
%                     app.UIAxes_3.Title.String='E-Field and H-Field'
                    
                elseif app.PlotTypeDropDown.Value=="Contour"
                    cla(app.UIAxes);
                    contourf(app.UIAxes, app.ZScale*1000, app.XScale*1000, real(Emag1));
                    app.UIAxes.XLabel.String='z (mm)';
                    app.UIAxes.YLabel.String='x (mm)';
                    app.UIAxes.XLim=[0 app.z];
                    app.UIAxes.YLim=[0 app.a];
                    app.UIAxes.Box='on';
                    app.UIAxes.PlotBoxAspectRatio=[1 rat 1];
                    app.UIAxes.Title.String='E-Field'
                    
                    cla(app.UIAxes_2);
                    contourf(app.UIAxes_2, app.ZScale*1000, app.XScale*1000, real(Hmag1));
                    app.UIAxes_2.XLabel.String='z (mm)';
                    app.UIAxes_2.YLabel.String='x (mm)';
                    app.UIAxes_2.XLim=[0 app.z];
                    app.UIAxes_2.YLim=[0 app.a];
                    app.UIAxes_2.Box='on';
                    app.UIAxes_2.PlotBoxAspectRatio=[1 rat 1];
                    app.UIAxes_2.Title.String='H-Field'
                    
                    cla(app.UIAxes_3);
                    app.UIAxes_3.Visible='off';
                    app.UIAxes_3.Legend.Visible='off'
%                     app.UIAxes_3.XLabel.String='x (mm)';
%                     app.UIAxes_3.YLabel.String='y (mm)';
%                     app.UIAxes_3.XLim=[0 app.a];
%                     app.UIAxes_3.YLim=[0 app.b];
%                     app.UIAxes_3.Box='on';
%                     app.UIAxes_3.PlotBoxAspectRatio=[rat 1 1];
%                     app.UIAxes_3.Title.String='E-Field and H-Field'
                end
            end
        end
        
        function results = updateTable(app)
            tableData=get(app.UITable, 'Data');
            %Calc table parameters;
            app.k=app.w.*sqrt(app.Er.*app.Eo.*app.Ur.*app.Uo);
            app.kc=sqrt((((app.ModeM.*pi)./(app.a./1000)).^2)+(((app.ModeN.*pi)./(app.b./1000)).^2));
            app.fc=[(1./(2.*pi.*sqrt(app.Er.*app.Eo.*app.Ur.*app.Uo))).*app.kc];
            app.B=sqrt(app.k.^2-app.kc.^2);
            app.Lg=(2*pi)./app.B;
            app.vp=app.w./app.B;
            app.n=sqrt((app.Uo.*app.Ur)./(app.Eo.*app.Er));
            
            if app.TEButton.Value==1
                app.wZ=(app.k.*app.n)./app.B;
            else
                app.wZ=(app.B.*app.n)./app.k;
            end
            
            %set table parameters
            tableData{1,2}=num2str(app.k);
            tableData{2,2}=num2str(app.fc/10.^9);
            tableData{3,2}=num2str(app.kc);
            tableData{4,2}=num2str(app.B);
            if isreal(app.B)
                tableData{5,2}='yes';
            else
                tableData{5,2}='no';
            end
            tableData{6,2}=num2str(app.Lg);
            tableData{7,2}=num2str(app.vp);
            tableData{8,2}=num2str(app.wZ);
            %push table parameters
            
            app.UITable.Data=tableData;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp)
            app.main2 = callingApp;
            app.a=22.86;
            app.b=10.16;
            app.z=50;
            app.ammEditField.Value=app.a;
            app.bmmEditField.Value=app.b;
            app.zmmEditField.Value=app.z;
            app.Er=1;
            app.Ur=1;
            app.PermittivityEditField.Value=app.Er;
            app.PermeabilityEditField.Value=app.Ur;
            app.Image.Visible='on';
            app.Image2.Visible='off';
            
            %Plot Setup
            app.XDensity=40;
            app.PlotDensityXEditField.Value=app.XDensity;
            app.XScale=linspace(0, app.a/1000, app.XDensity);
            app.YDensity=20;
            app.PlotDensityYEditField.Value=app.YDensity;
            app.YScale=linspace(0, app.b/1000, app.YDensity);
            app.ZDensity=40;
            app.PlotDensityZEditField.Value=app.ZDensity;
            app.ZScale=linspace(0, app.z/1000, app.ZDensity);
            
            %Waveguide Operation
            app.ModeM=1;
            app.mEditField.Value=app.ModeM;
            app.ModeN=0;
            app.nEditField.Value=app.ModeN;
            app.OperationalFreqisLessthantheCutoffFreqLabel.Visible='off'
            
            %Waveguide parameters
            app.UITable.Data={'Wavenumber (rad/m)', 0; 'Cutoff Freq. (GHz)', 0;...
                'Cutoff Wavenumber (rad/m)', 0; 'Propagation Constant (rad/m)', 0;...
                'Propagating?','N/A'; 'Guide Wavelength (m)', 0; 'Phase Velocity (m/s)', 0;...
                'Wave Impedance', 0};
            
            app.fc=0;
            app.kc=0;
            app.f=10.*10^9;
            app.OperationalFrequencyGHzEditField.Value=10;
            app.w=2.*pi.*app.f;
            app.kc=sqrt((((app.ModeM.*pi)./(app.a./1000)).^2)+(((app.ModeN.*pi)./(app.b./1000)).^2));
            app.fc=[(1./(2.*pi.*sqrt(app.Er.*app.Eo.*app.Ur.*app.Uo))).*app.kc];
            
            app.ZSlider.Visible='on';
            app.XSlider.Visible='off';
            app.XSliderLabel.Visible='off';
            app.YSlider.Visible='off';
            app.YSliderLabel.Visible='off'
            app.ZSlider.Limits=[0 app.z];
            app.ZSlider.Value=1;
            app.PlotsOutdatedLabel.Visible='on';
            
            updateTable(app);
        end

        % Value changed function: GuidebandsDropDown
        function GuidebandsDropDownValueChanged(app, event)
            value = app.GuidebandsDropDown.Value;
            switch value
                case "Custom"
                    app.ammEditField.Value=0;
                    app.bmmEditField.Value=0;
                    app.zmmEditField.Value=app.z;
                    app.a=app.ammEditField.Value;
                    app.b=app.bmmEditField.Value;
                    app.z=app.zmmEditField.Value;
                    updateTable(app);
                    if app.f<app.fc
                        app.OperationalFrequencyGHzEditField.BackgroundColor=[1 0 0];
                        app.OperationalFreqisLessthantheCutoffFreqLabel.Visible='on';
                    else
                        app.OperationalFrequencyGHzEditField.BackgroundColor=[1 1 1];
                        app.OperationalFreqisLessthantheCutoffFreqLabel.Visible='off';
                    end
                case "L-band"
                    app.ammEditField.Value=165.1;
                    app.bmmEditField.Value=82.55;
                    app.zmmEditField.Value=app.z;
                    app.a=app.ammEditField.Value;
                    app.b=app.bmmEditField.Value;
                    app.z=app.zmmEditField.Value;
                    app.XScale=linspace(0, app.a/1000, app.XDensity);
                    app.YScale=linspace(0, app.b/1000, app.YDensity);
                    updateTable(app);
                    if app.f<app.fc
                        app.OperationalFrequencyGHzEditField.BackgroundColor=[1 0 0];
                        app.OperationalFreqisLessthantheCutoffFreqLabel.Visible='on';
                    else
                        app.OperationalFrequencyGHzEditField.BackgroundColor=[1 1 1];
                        app.OperationalFreqisLessthantheCutoffFreqLabel.Visible='off';
                    end
                case "R-band"
                    app.ammEditField.Value=109.22;
                    app.bmmEditField.Value=54.61;
                    app.zmmEditField.Value=app.z;
                    app.a=app.ammEditField.Value;
                    app.b=app.bmmEditField.Value;
                    app.z=app.zmmEditField.Value;
                    app.XScale=linspace(0, app.a/1000, app.XDensity);
                    app.YScale=linspace(0, app.b/1000, app.YDensity);
                    updateTable(app);
                    if app.f<app.fc
                        app.OperationalFrequencyGHzEditField.BackgroundColor=[1 0 0];
                        app.OperationalFreqisLessthantheCutoffFreqLabel.Visible='on';
                    else
                        app.OperationalFrequencyGHzEditField.BackgroundColor=[1 1 1];
                        app.OperationalFreqisLessthantheCutoffFreqLabel.Visible='off';
                    end
                case "S-band"
                    app.ammEditField.Value=72.14
                    app.bmmEditField.Value=34.04;
                    app.zmmEditField.Value=app.z;
                    app.a=app.ammEditField.Value;
                    app.b=app.bmmEditField.Value;
                    app.z=app.zmmEditField.Value;
                    app.XScale=linspace(0, app.a/1000, app.XDensity);
                    app.YScale=linspace(0, app.b/1000, app.YDensity);
                    updateTable(app);
                    if app.f<app.fc
                        app.OperationalFrequencyGHzEditField.BackgroundColor=[1 0 0];
                        app.OperationalFreqisLessthantheCutoffFreqLabel.Visible='on';
                    else
                        app.OperationalFrequencyGHzEditField.BackgroundColor=[1 1 1];
                        app.OperationalFreqisLessthantheCutoffFreqLabel.Visible='off';
                    end
                case "G-band"
                    app.ammEditField.Value=47.55;
                    app.bmmEditField.Value=22.15;
                    app.zmmEditField.Value=0;
                    app.a=app.ammEditField.Value;
                    app.b=app.bmmEditField.Value;
                    app.z=app.zmmEditField.Value;
                    app.XScale=linspace(0, app.a/1000, app.XDensity);
                    app.YScale=linspace(0, app.b/1000, app.YDensity);
                    updateTable(app);
                    if app.f<app.fc
                        app.OperationalFrequencyGHzEditField.BackgroundColor=[1 0 0];
                        app.OperationalFreqisLessthantheCutoffFreqLabel.Visible='on';
                    else
                        app.OperationalFrequencyGHzEditField.BackgroundColor=[1 1 1];
                        app.OperationalFreqisLessthantheCutoffFreqLabel.Visible='off';
                    end
                case "J-band"
                    app.ammEditField.Value=34.85;
                    app.bmmEditField.Value=15.8;
                    app.zmmEditField.Value=app.z;
                    app.a=app.ammEditField.Value;
                    app.b=app.bmmEditField.Value;
                    app.z=app.zmmEditField.Value;
                    app.XScale=linspace(0, app.a/1000, app.XDensity);
                    app.YScale=linspace(0, app.b/1000, app.YDensity);
                    updateTable(app);
                    if app.f<app.fc
                        app.OperationalFrequencyGHzEditField.BackgroundColor=[1 0 0];
                        app.OperationalFreqisLessthantheCutoffFreqLabel.Visible='on';
                    else
                        app.OperationalFrequencyGHzEditField.BackgroundColor=[1 1 1];
                        app.OperationalFreqisLessthantheCutoffFreqLabel.Visible='off';
                    end
                case "H-band"
                    app.ammEditField.Value=28.5;
                    app.bmmEditField.Value=12.62;
                    app.zmmEditField.Value=app.z;
                    app.a=app.ammEditField.Value;
                    app.b=app.bmmEditField.Value;
                    app.z=app.zmmEditField.Value;
                    app.XScale=linspace(0, app.a/1000, app.XDensity);
                    app.YScale=linspace(0, app.b/1000, app.YDensity);
                    updateTable(app);
                    if app.f<app.fc
                        app.OperationalFrequencyGHzEditField.BackgroundColor=[1 0 0];
                        app.OperationalFreqisLessthantheCutoffFreqLabel.Visible='on';
                    else
                        app.OperationalFrequencyGHzEditField.BackgroundColor=[1 1 1];
                        app.OperationalFreqisLessthantheCutoffFreqLabel.Visible='off';
                    end
                case "X-band"
                    app.ammEditField.Value=22.86;
                    app.bmmEditField.Value=10.16;
                    app.zmmEditField.Value=app.z;
                    app.a=app.ammEditField.Value;
                    app.b=app.bmmEditField.Value;
                    app.z=app.zmmEditField.Value;
                    app.XScale=linspace(0, app.a/1000, app.XDensity);
                    app.YScale=linspace(0, app.b/1000, app.YDensity);
                    updateTable(app);
                    if app.f<app.fc
                        app.OperationalFrequencyGHzEditField.BackgroundColor=[1 0 0];
                        app.OperationalFreqisLessthantheCutoffFreqLabel.Visible='on';
                    else
                        app.OperationalFrequencyGHzEditField.BackgroundColor=[1 1 1];
                        app.OperationalFreqisLessthantheCutoffFreqLabel.Visible='off';
                    end
                case "Ku-band"
                    app.ammEditField.Value=15.8;
                    app.bmmEditField.Value=7.9;
                    app.zmmEditField.Value=app.z;
                    app.a=app.ammEditField.Value;
                    app.b=app.bmmEditField.Value;
                    app.z=app.zmmEditField.Value;
                    app.XScale=linspace(0, app.a/1000, app.XDensity);
                    app.YScale=linspace(0, app.b/1000, app.YDensity);
                    updateTable(app);
                    if app.f<app.fc
                        app.OperationalFrequencyGHzEditField.BackgroundColor=[1 0 0];
                        app.OperationalFreqisLessthantheCutoffFreqLabel.Visible='on';
                    else
                        app.OperationalFrequencyGHzEditField.BackgroundColor=[1 1 1];
                        app.OperationalFreqisLessthantheCutoffFreqLabel.Visible='off';
                    end
                case "K-band"
                    app.ammEditField.Value=10.7;
                    app.bmmEditField.Value=4.3;
                    app.zmmEditField.Value=app.z;
                    app.a=app.ammEditField.Value;
                    app.b=app.bmmEditField.Value;
                    app.z=app.zmmEditField.Value;
                    app.XScale=linspace(0, app.a/1000, app.XDensity);
                    app.YScale=linspace(0, app.b/1000, app.YDensity);
                    updateTable(app);
                    if app.f<app.fc
                        app.OperationalFrequencyGHzEditField.BackgroundColor=[1 0 0];
                        app.OperationalFreqisLessthantheCutoffFreqLabel.Visible='on';
                    else
                        app.OperationalFrequencyGHzEditField.BackgroundColor=[1 1 1];
                        app.OperationalFreqisLessthantheCutoffFreqLabel.Visible='off';
                    end
                case "Ka-band"
                    app.ammEditField.Value=7.11;
                    app.bmmEditField.Value=3.56;
                    app.zmmEditField.Value=app.z;
                    app.a=app.ammEditField.Value;
                    app.b=app.bmmEditField.Value;
                    app.z=app.zmmEditField.Value;
                    app.XScale=linspace(0, app.a/1000, app.XDensity);
                    app.YScale=linspace(0, app.b/1000, app.YDensity);
                    updateTable(app);
                    if app.f<app.fc
                        app.OperationalFrequencyGHzEditField.BackgroundColor=[1 0 0];
                        app.OperationalFreqisLessthantheCutoffFreqLabel.Visible='on';
                    else
                        app.OperationalFrequencyGHzEditField.BackgroundColor=[1 1 1];
                        app.OperationalFreqisLessthantheCutoffFreqLabel.Visible='off';
                    end
            end
        end

        % Value changed function: ammEditField
        function ammEditFieldValueChanged(app, event)
            value = app.ammEditField.Value;
            app.a=value;
            app.XScale=linspace(0, app.a/1000, app.XDensity);
            app.GuidebandsDropDown.Value="Custom";
            updateTable(app);
            app.PlotsOutdatedLabel.Visible='on';
            app.kc=sqrt((((app.ModeM.*pi)./(app.a./1000)).^2)+(((app.ModeN.*pi)./(app.b./1000)).^2));
            app.fc=[(1./(2.*pi.*sqrt(app.Er.*app.Eo.*app.Ur.*app.Uo))).*app.kc];
            if app.f<app.fc
                app.OperationalFrequencyGHzEditField.BackgroundColor=[1 0 0];
                app.OperationalFreqisLessthantheCutoffFreqLabel.Visible='on';
            else
                app.OperationalFrequencyGHzEditField.BackgroundColor=[1 1 1];
                app.OperationalFreqisLessthantheCutoffFreqLabel.Visible='off';
            end
        end

        % Value changed function: bmmEditField
        function bmmEditFieldValueChanged(app, event)
            value = app.bmmEditField.Value;
            app.b=value;
            app.YScale=linspace(0, app.b/1000, app.YDensity);
            app.GuidebandsDropDown.Value="Custom";
            updateTable(app);
            app.PlotsOutdatedLabel.Visible='on';
            app.kc=sqrt((((app.ModeM.*pi)./(app.a./1000)).^2)+(((app.ModeN.*pi)./(app.b./1000)).^2));
            app.fc=[(1./(2.*pi.*sqrt(app.Er.*app.Eo.*app.Ur.*app.Uo))).*app.kc];
            if app.f<app.fc
                app.OperationalFrequencyGHzEditField.BackgroundColor=[1 0 0];
                app.OperationalFreqisLessthantheCutoffFreqLabel.Visible='on';
            else
                app.OperationalFrequencyGHzEditField.BackgroundColor=[1 1 1];
                app.OperationalFreqisLessthantheCutoffFreqLabel.Visible='off';
            end
        end

        % Value changed function: zmmEditField
        function zmmEditFieldValueChanged(app, event)
            value = app.zmmEditField.Value;
            app.z=value;
            app.ZScale=linspace(0, app.z/1000, app.ZDensity)
            app.PlotsOutdatedLabel.Visible='on';
        end

        % Value changed function: PermittivityEditField
        function PermittivityEditFieldValueChanged(app, event)
            value = app.PermittivityEditField.Value;
            app.Er=value;
            if app.Er==1 && app.Ur==1
                app.Image.Visible='on';
                app.Image2.Visible='off';
            else
                app.Image.Visible='off';
                app.Image2.Visible='on';
            end
            app.PlotsOutdatedLabel.Visible='on';
            app.kc=sqrt((((app.ModeM.*pi)./(app.a./1000)).^2)+(((app.ModeN.*pi)./(app.b./1000)).^2));
            app.fc=[(1./(2.*pi.*sqrt(app.Er.*app.Eo.*app.Ur.*app.Uo))).*app.kc];
            if app.f<app.fc
                app.OperationalFrequencyGHzEditField.BackgroundColor=[1 0 0];
                app.OperationalFreqisLessthantheCutoffFreqLabel.Visible='on';
            else
                app.OperationalFrequencyGHzEditField.BackgroundColor=[1 1 1];
                app.OperationalFreqisLessthantheCutoffFreqLabel.Visible='off';
            end
        end

        % Value changed function: PermeabilityEditField
        function PermeabilityEditFieldValueChanged(app, event)
            value = app.PermeabilityEditField.Value;
            app.Ur=value;
            if app.Er==1 && app.Ur==1
                app.Image.Visible='on';
                app.Image2.Visible='off';
            else
                app.Image.Visible='off';
                app.Image2.Visible='on';
            end
            app.PlotsOutdatedLabel.Visible='on';
            app.kc=sqrt((((app.ModeM.*pi)./(app.a./1000)).^2)+(((app.ModeN.*pi)./(app.b./1000)).^2));
            app.fc=[(1./(2.*pi.*sqrt(app.Er.*app.Eo.*app.Ur.*app.Uo))).*app.kc];
            if app.f<app.fc
                app.OperationalFrequencyGHzEditField.BackgroundColor=[1 0 0];
                app.OperationalFreqisLessthantheCutoffFreqLabel.Visible='on';
            else
                app.OperationalFrequencyGHzEditField.BackgroundColor=[1 1 1];
                app.OperationalFreqisLessthantheCutoffFreqLabel.Visible='off';
            end
        end

        % Value changed function: PlotDensityXEditField
        function PlotDensityXEditFieldValueChanged(app, event)
            value = app.PlotDensityXEditField.Value;
            app.XDensity=value;
            app.XScale=linspace(0, app.a/1000, app.XDensity);
            app.PlotsOutdatedLabel.Visible='on';
        end

        % Value changed function: PlotDensityYEditField
        function PlotDensityYEditFieldValueChanged(app, event)
            value = app.PlotDensityYEditField.Value;
            app.YDensity=value;
            app.YScale=linspace(0, app.b/1000, app.YDensity);
            app.PlotsOutdatedLabel.Visible='on';
        end

        % Value changed function: PlotDensityZEditField
        function PlotDensityZEditFieldValueChanged(app, event)
            value = app.PlotDensityZEditField.Value;
            app.ZDensity=value;
            app.ZScale=linspace(0, app.z/1000, app.ZDensity)
            app.PlotsOutdatedLabel.Visible='on';
        end

        % Value changed function: mEditField
        function mEditFieldValueChanged(app, event)
            value = app.mEditField.Value;
            app.ModeM=value;
            if app.ModeN==0 && app.ModeM==0
                app.mEditField.BackgroundColor=[1 0 0];
                app.PlotFieldsButton.Visible='off';
            else
                app.mEditField.BackgroundColor=[1 1 1];
                app.PlotFieldsButton.Visible='on';
            end
            updateTable(app);
            app.PlotsOutdatedLabel.Visible='on';
            app.kc=sqrt((((app.ModeM.*pi)./(app.a./1000)).^2)+(((app.ModeN.*pi)./(app.b./1000)).^2));
            app.fc=[(1./(2.*pi.*sqrt(app.Er.*app.Eo.*app.Ur.*app.Uo))).*app.kc];
            if app.f<app.fc
                app.OperationalFrequencyGHzEditField.BackgroundColor=[1 0 0];
                app.OperationalFreqisLessthantheCutoffFreqLabel.Visible='on';
            else
                app.OperationalFrequencyGHzEditField.BackgroundColor=[1 1 1];
                app.OperationalFreqisLessthantheCutoffFreqLabel.Visible='off';
            end
        end

        % Value changed function: nEditField
        function nEditFieldValueChanged(app, event)
            value = app.nEditField.Value;
            app.ModeN=value;
            if app.ModeM==0 && app.ModeN==0
                app.nEditField.BackgroundColor=[1 0 0];
                app.PlotFieldsButton.Visible='off';
            else
                app.nEditField.BackgroundColor=[1 1 1];
                app.PlotFieldsButton.Visible='on';
            end
            updateTable(app);
            app.PlotsOutdatedLabel.Visible='on';
            app.kc=sqrt((((app.ModeM.*pi)./(app.a./1000)).^2)+(((app.ModeN.*pi)./(app.b./1000)).^2));
            app.fc=[(1./(2.*pi.*sqrt(app.Er.*app.Eo.*app.Ur.*app.Uo))).*app.kc];
            if app.f<app.fc
                app.OperationalFrequencyGHzEditField.BackgroundColor=[1 0 0];
                app.OperationalFreqisLessthantheCutoffFreqLabel.Visible='on';
            else
                app.OperationalFrequencyGHzEditField.BackgroundColor=[1 1 1];
                app.OperationalFreqisLessthantheCutoffFreqLabel.Visible='off';
            end
        end

        % Button pushed function: PlotFieldsButton
        function PlotFieldsButtonPushed(app, event)
            %Calc fields
            [app.Ex app.Ey app.Ez app.Hx app.Hy app.Hz] = CalcFields(app);
            % 说明右侧的电场和磁场的热力图含义为绝对值
            app.Emag=sqrt(app.Ex.^2 + app.Ey.^2 + app.Ez.^2);
            app.Hmag=sqrt(app.Hx.^2 + app.Hy.^2 + app.Hz.^2);
            
            cla(app.UIAxes_4);
            if app.CheckBox1.Value
                h=quiver3(app.UIAxes_4, app.xx, app.zz, app.yy, real(app.Ex), real(app.Ez), real(app.Ey),0.5);
%                 set(h , 'maxheadsize' , 0.02);	%除去箭头
            end
            hold(app.UIAxes_4,'on');
            if app.CheckBox2.Value
                quiver3(app.UIAxes_4, app.xx, app.zz, app.yy, real(app.Hx), real(app.Hz), real(app.Hy),0.5,'r');
            end
            % perspective
            if app.perspectiveDropDown.Value == "y-z"
                    view(app.UIAxes_4,[90,0])
            elseif app.perspectiveDropDown.Value == "y-x"
                    view(app.UIAxes_4,[0,0]) 
            elseif app.perspectiveDropDown.Value == "x-z"
                    view(app.UIAxes_4,[90,90]) 
            end
%             legend(app.UIAxes_4,'E','H')
            hold(app.UIAxes_4,'off');
            app.UIAxes_4.XLabel.String='x (m)';
            app.UIAxes_4.YLabel.String='z (m)';
            app.UIAxes_4.ZLabel.String='y (m)';
            app.UIAxes_4.PlotBoxAspectRatio=[app.a app.z app.b];
            app.UIAxes_4.Title.String='E-field and H-field';
            app.UIAxes_4.XLim=[0 app.a/1000];
            app.UIAxes_4.ZLim=[0 app.b/1000];
            app.UIAxes_4.YLim=[0 app.z/1000];
            app.UIAxes_4.Box='on';
            plotF(app);
            
            %Remove warning message
            updateTable(app);
            app.PlotsOutdatedLabel.Visible='off';
        end

        % Value changed function: OperationalFrequencyGHzEditField
        function OperationalFrequencyGHzEditFieldValueChanged(app, event)
            value = app.OperationalFrequencyGHzEditField.Value;
            updateTable(app);
            app.f=value.*10^9;
            app.w=2.*pi.*value.*10^9;
            app.kc=sqrt((((app.ModeM.*pi)./(app.a./1000)).^2)+(((app.ModeN.*pi)./(app.b./1000)).^2));
            app.fc=(1./(2.*pi.*sqrt(app.Er.*app.Eo.*app.Ur.*app.Uo))).*app.kc;
            if app.f<app.fc
                app.OperationalFrequencyGHzEditField.BackgroundColor=[1 0 0];
                app.OperationalFreqisLessthantheCutoffFreqLabel.Visible='on';
            else
                app.OperationalFrequencyGHzEditField.BackgroundColor=[1 1 1];
                app.OperationalFreqisLessthantheCutoffFreqLabel.Visible='off';
            end
            app.PlotsOutdatedLabel.Visible='on';
        end

        % Value changed function: CrossSectionDropDown
        function CrossSectionDropDownValueChanged(app, event)
            value = app.CrossSectionDropDown.Value;
            app.PlotsOutdatedLabel.Visible='on';
            switch value
                case 'x-y'
                    app.XSlider.Visible='off';
                    app.YSlider.Visible='off';
                    app.ZSlider.Visible='on';
                    app.ZSlider.Limits=[0 app.z];
                    app.ZSlider.Value=1;
                case 'y-z'
                    app.XSlider.Visible='on';
                    app.YSlider.Visible='off';
                    app.ZSlider.Visible='off';
                    app.XSlider.Limits=[0 app.a];
                    app.XSlider.Value=app.a/2;
                case 'x-z'
                    app.XSlider.Visible='off';
                    app.YSlider.Visible='on';
                    app.ZSlider.Visible='off';
                    app.YSlider.Limits=[0 app.b];
                    app.YSlider.Value=app.b/2;
            end
        end

        % Value changed function: PlotTypeDropDown
        function PlotTypeDropDownValueChanged(app, event)
            value = app.PlotTypeDropDown.Value;
            app.PlotsOutdatedLabel.Visible='on';
        end

        % Value changed function: ZSlider
        function ZSliderValueChanged(app, event)
            value = app.ZSlider.Value;
            plotF(app);
        end

        % Value changed function: XSlider
        function XSliderValueChanged(app, event)
            value = app.XSlider.Value;
            plotF(app);
        end

        % Value changed function: YSlider
        function YSliderValueChanged(app, event)
            value = app.YSlider.Value;
            plotF(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.main2.Button_3.Enable = 'on';
            app.main2.UIFigure.Visible = 'on';
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.ZSliderLabel = uilabel(app.UIFigure);
            app.ZSliderLabel.Text = 'Z Slider';
            app.ZSlider = uislider(app.UIFigure);
            app.ZSlider.ValueChangedFcn = createCallbackFcn(app, @ZSliderValueChanged, true);
            app.WaveguideGeometryPanel = uipanel(app.UIFigure);
            app.WaveguideGeometryPanel.Title = 'Waveguide Geometry';
            app.ammEditFieldLabel = uilabel(app.WaveguideGeometryPanel);
            app.ammEditFieldLabel.Text = 'a (mm)';
            app.ammEditField = uieditfield(app.WaveguideGeometryPanel, 'numeric');
            app.ammEditField.ValueChangedFcn = createCallbackFcn(app, @ammEditFieldValueChanged, true);
            app.bmmEditFieldLabel = uilabel(app.WaveguideGeometryPanel);
            app.bmmEditFieldLabel.Text = 'b (mm)';
            app.bmmEditField = uieditfield(app.WaveguideGeometryPanel, 'numeric');
            app.bmmEditField.ValueChangedFcn = createCallbackFcn(app, @bmmEditFieldValueChanged, true);
            app.zmmEditFieldLabel = uilabel(app.WaveguideGeometryPanel);
            app.zmmEditFieldLabel.Text = 'z (mm)';
            app.zmmEditField = uieditfield(app.WaveguideGeometryPanel, 'numeric');
            app.zmmEditField.ValueChangedFcn = createCallbackFcn(app, @zmmEditFieldValueChanged, true);
            app.WaveguideFillLabel = uilabel(app.WaveguideGeometryPanel);
            app.WaveguideFillLabel.Text = 'Waveguide Fill';
            app.PermittivityLabel = uilabel(app.WaveguideGeometryPanel);
            app.PermittivityLabel.Text = {'Permittivity'; '(相对介电常数)'};
            app.PermittivityEditField = uieditfield(app.WaveguideGeometryPanel, 'numeric');
            app.PermittivityEditField.ValueChangedFcn = createCallbackFcn(app, @PermittivityEditFieldValueChanged, true);
            app.PermeabilityLabel = uilabel(app.WaveguideGeometryPanel);
            app.PermeabilityLabel.Text = {'Permeability'; '（相对磁导率）'};
            app.PermeabilityEditField = uieditfield(app.WaveguideGeometryPanel, 'numeric');
            app.PermeabilityEditField.ValueChangedFcn = createCallbackFcn(app, @PermeabilityEditFieldValueChanged, true);
            app.Image = uiimage(app.WaveguideGeometryPanel);
            app.Image.ImageSource = 'WG1.PNG';
            app.WaveguideDimensionsLabel = uilabel(app.WaveguideGeometryPanel);
            app.WaveguideDimensionsLabel.Text = 'Waveguide Dimensions';
            app.GuidebandsDropDownLabel = uilabel(app.WaveguideGeometryPanel);
            app.GuidebandsDropDownLabel.Text = 'Guide bands';
            app.GuidebandsDropDown = uidropdown(app.WaveguideGeometryPanel);
            app.GuidebandsDropDown.Items = {'Custom', 'L-band', 'R-band', 'S-band', 'G-band', 'J-band', 'H-band', 'X-band', 'Ku-band', 'K-band', 'Ka-band'};
            app.GuidebandsDropDown.ValueChangedFcn = createCallbackFcn(app, @GuidebandsDropDownValueChanged, true);
            app.GuidebandsDropDown.Value = 'Custom';
            app.UsedefaultwaveguidesettingsorcustomsizesLabel = uilabel(app.WaveguideGeometryPanel);
            app.UsedefaultwaveguidesettingsorcustomsizesLabel.Text = {'Use default waveguide settings or custom sizes'; ''};
            app.Label = uilabel(app.WaveguideGeometryPanel);
            app.Label.Text = '';
            app.Image2 = uiimage(app.WaveguideGeometryPanel);
            app.Image2.ImageSource = 'WG2.PNG';
            app.WaveguideOperationPanel = uipanel(app.UIFigure);
            app.WaveguideOperationPanel.Title = 'Waveguide Operation';
            app.UIAxes_4 = uiaxes(app.WaveguideOperationPanel);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            app.WaveguideModeTypeButtonGroup = uibuttongroup(app.WaveguideOperationPanel);
            app.WaveguideModeTypeButtonGroup.Title = 'Waveguide Mode Type';
            app.TEButton = uiradiobutton(app.WaveguideModeTypeButtonGroup);
            app.TEButton.Text = 'TE';
            app.TEButton.Value = true;
            app.TMButton = uiradiobutton(app.WaveguideModeTypeButtonGroup);
            app.TMButton.Text = 'TM';
            app.mEditFieldLabel = uilabel(app.WaveguideOperationPanel);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.WaveguideOperationPanel, 'numeric');
            app.mEditField.RoundFractionalValues = 'on';
            app.mEditField.ValueChangedFcn = createCallbackFcn(app, @mEditFieldValueChanged, true);
            app.nEditFieldLabel = uilabel(app.WaveguideOperationPanel);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.WaveguideOperationPanel, 'numeric');
            app.nEditField.RoundFractionalValues = 'on';
            app.nEditField.ValueChangedFcn = createCallbackFcn(app, @nEditFieldValueChanged, true);
            app.OperationalFrequencyGHzEditFieldLabel = uilabel(app.WaveguideOperationPanel);
            app.OperationalFrequencyGHzEditFieldLabel.Text = 'Operational Frequency (GHz)';
            app.OperationalFrequencyGHzEditField = uieditfield(app.WaveguideOperationPanel, 'numeric');
            app.OperationalFrequencyGHzEditField.ValueChangedFcn = createCallbackFcn(app, @OperationalFrequencyGHzEditFieldValueChanged, true);
            app.CalculatedWaveguideParametersLabel = uilabel(app.WaveguideOperationPanel);
            app.CalculatedWaveguideParametersLabel.Text = 'Calculated Waveguide Parameters';
            app.ModeNumberLabel = uilabel(app.WaveguideOperationPanel);
            app.ModeNumberLabel.Text = 'Mode Number';
            app.UITable = uitable(app.WaveguideOperationPanel);
            app.UITable.ColumnName = {'Parameter'; 'Value'};
            app.UITable.RowName = {''};
            app.UITable.ColumnSortable = [false false];
            app.UITable.ColumnEditable = [false false];
            app.OperationalFreqisLessthantheCutoffFreqLabel = uilabel(app.WaveguideOperationPanel);
            app.OperationalFreqisLessthantheCutoffFreqLabel.Text = 'Operational Freq. is Less than the Cutoff Freq.';
            app.DFieldsinWaveguideLabel = uilabel(app.WaveguideOperationPanel);
            app.DFieldsinWaveguideLabel.Text = '3D Fields in Waveguide';
            app.CheckBox1 = uicheckbox(app.WaveguideOperationPanel);
            app.CheckBox1.Text = '';
            app.CheckBox1.Value = true;
            app.CheckBox2 = uicheckbox(app.WaveguideOperationPanel);
            app.CheckBox2.Text = '';
            app.CheckBox2.Value = true;
            app.ELabel = uilabel(app.WaveguideOperationPanel);
            app.ELabel.Text = 'E';
            app.HLabel = uilabel(app.WaveguideOperationPanel);
            app.HLabel.Text = 'H';
            app.Label_4 = uilabel(app.WaveguideOperationPanel);
            app.Label_4.Text = {''; 'perspective'};
            app.perspectiveDropDown = uidropdown(app.WaveguideOperationPanel);
            app.perspectiveDropDown.Items = {'default', 'y-z', 'y-x', 'x-z'};
            app.perspectiveDropDown.Value = 'default';
            app.PlotOptionsPanel = uipanel(app.UIFigure);
            app.PlotOptionsPanel.Title = 'Plot Options';
            app.PlotTypeDropDownLabel = uilabel(app.PlotOptionsPanel);
            app.PlotTypeDropDownLabel.Text = 'Plot Type';
            app.PlotTypeDropDown = uidropdown(app.PlotOptionsPanel);
            app.PlotTypeDropDown.Items = {'Quiver', 'Quiver w/ Contour', 'Image', 'Contour'};
            app.PlotTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotTypeDropDownValueChanged, true);
            app.PlotTypeDropDown.Value = 'Quiver';
            app.CrossSectionDropDownLabel = uilabel(app.PlotOptionsPanel);
            app.CrossSectionDropDownLabel.Text = 'Cross Section';
            app.CrossSectionDropDown = uidropdown(app.PlotOptionsPanel);
            app.CrossSectionDropDown.Items = {'x-y', 'y-z', 'x-z'};
            app.CrossSectionDropDown.ValueChangedFcn = createCallbackFcn(app, @CrossSectionDropDownValueChanged, true);
            app.CrossSectionDropDown.Value = 'x-y';
            app.PlotDensityXEditFieldLabel = uilabel(app.PlotOptionsPanel);
            app.PlotDensityXEditFieldLabel.Text = 'Plot Density: X';
            app.PlotDensityXEditField = uieditfield(app.PlotOptionsPanel, 'numeric');
            app.PlotDensityXEditField.ValueChangedFcn = createCallbackFcn(app, @PlotDensityXEditFieldValueChanged, true);
            app.PlotDensityYEditFieldLabel = uilabel(app.PlotOptionsPanel);
            app.PlotDensityYEditFieldLabel.Text = 'Plot Density: Y';
            app.PlotDensityYEditField = uieditfield(app.PlotOptionsPanel, 'numeric');
            app.PlotDensityYEditField.ValueChangedFcn = createCallbackFcn(app, @PlotDensityYEditFieldValueChanged, true);
            app.PlotDensityZEditFieldLabel = uilabel(app.PlotOptionsPanel);
            app.PlotDensityZEditFieldLabel.Text = 'Plot Density: Z';
            app.PlotDensityZEditField = uieditfield(app.PlotOptionsPanel, 'numeric');
            app.PlotDensityZEditField.ValueChangedFcn = createCallbackFcn(app, @PlotDensityZEditFieldValueChanged, true);
            app.PlotFieldsButton = uibutton(app.PlotOptionsPanel, 'push');
            app.PlotFieldsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotFieldsButtonPushed, true);
            app.PlotFieldsButton.Text = 'Plot Fields';
            app.PlotsOutdatedLabel = uilabel(app.PlotOptionsPanel);
            app.PlotsOutdatedLabel.Text = 'Plots Outdated';
            app.XSliderLabel = uilabel(app.UIFigure);
            app.XSliderLabel.Text = 'X Slider';
            app.XSlider = uislider(app.UIFigure);
            app.XSlider.ValueChangedFcn = createCallbackFcn(app, @XSliderValueChanged, true);
            app.YSliderLabel = uilabel(app.UIFigure);
            app.YSliderLabel.Text = 'Y Slider';
            app.YSlider = uislider(app.UIFigure);
            app.YSlider.ValueChangedFcn = createCallbackFcn(app, @YSliderValueChanged, true);
            app.Panel = uipanel(app.UIFigure);
            app.WaveguideSimulatorLabel = uilabel(app.Panel);
            app.WaveguideSimulatorLabel.Text = 'Waveguide Simulator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end