classdef main2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Label_3   matlab.ui.control.Label
        Button_3  matlab.ui.control.Button
        Label_2   matlab.ui.control.Label
        Label     matlab.ui.control.Label
        Button_2  matlab.ui.control.Button
        Button    matlab.ui.control.Button
        Image     matlab.ui.control.Image
    end

    
    properties (Access = private)
        main1
        sub1
        sub2
        sub3
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp)
            app.main1 = callingApp;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.Button.Enable = 'off';
            app.UIFigure.Visible = 'off';
            app.sub1 = app1(app);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.Button_2.Enable = 'off';
            app.UIFigure.Visible = 'off';
            app.sub2 = app2(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.main1.UIFigure.Visible = 'on';
            delete(app)
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.Button_3.Enable = 'off';
            app.UIFigure.Visible = 'off';
            app.sub3 = app3(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'main2_3.jpg';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'main2_1.jpg';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'main_2_5.jpg';
            app.Button_2.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '均匀平面电磁波';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'均匀平面电磁波的'; '入射、反射、折射'};
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'main2_2.jpg';
            app.Button_3.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '微波      波导';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end